classdef gui4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        HasilPengenalanBuahdenganPengolahanCitraPanel  matlab.ui.container.Panel
        FruitKNNLabel        matlab.ui.control.Label
        NamabuahCNNLabel_2   matlab.ui.control.Label
        HasilPengenalanBuaholehPretrainedCNNPanel  matlab.ui.container.Panel
        ProbabilityCNNLabel  matlab.ui.control.Label
        ProbCNNLabel         matlab.ui.control.Label
        FruitCNNLabel        matlab.ui.control.Label
        NamabuahCNNLabel     matlab.ui.control.Label
        ClassifyButton       matlab.ui.control.Button
        FilenameLabel        matlab.ui.control.Label
        FileinputLabel       matlab.ui.control.Label
        UploadButton         matlab.ui.control.Button
        TUCIL4IF4073Label    matlab.ui.control.Label
        OriginalImageAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageUploaded % uploaded image
        GrayscaleImage % the grayscale ver of uploaded image
        IsGrayscale % is the uploaded image grayscale or not
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            % read image file
            [filename,filepath] = uigetfile('../img/*.jpg;*.tif;*.png;*.gif;*.jpeg;*.bmp', 'Select File to Open');
            if ischar(filepath)
                fullname = [filepath, filename];
                app.ImageUploaded= imread(fullname);
    
                % get image type (grayscale/ rgb)
                app.IsGrayscale = Helper.isGrayscale(app.ImageUploaded);
                if (app.IsGrayscale)
                    app.GrayscaleImage = app.ImageUploaded;
                else
                    app.GrayscaleImage = rgb2gray(app.ImageUploaded);
                end
    
                % show image
                app.FilenameLabel.Text = filename;
                set(app.OriginalImageAxes, 'visible', 'on')
                imshow(app.ImageUploaded, 'Parent', app.OriginalImageAxes)
            end
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            % fruit recognition using image processing method
            klasifikasi = ImageProcessingClassifier.predict(app.ImageUploaded);
            if klasifikasi == 1
                app.FruitKNNLabel.Text = 'Apple';
            elseif klasifikasi == 2
                app.FruitKNNLabel.Text = 'Banana';
            elseif klasifikasi == 3
                app.FruitKNNLabel.Text = 'Orange';
            elseif klasifikasi == 4
                app.FruitKNNLabel.Text = 'Melon';
            elseif klasifikasi == 5
                app.FruitKNNLabel.Text = 'Pear';
            end

            % fruit recognition using fine-tuned CNN
            [CNNpred, score] = PretrainedCNNClassifier.predict(app.ImageUploaded);
            app.FruitCNNLabel.Text = CNNpred;
            app.ProbCNNLabel.Text = score;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImageAxes = uiaxes(app.UIFigure);
            title(app.OriginalImageAxes, 'ORIGINAL IMAGE')
            app.OriginalImageAxes.XTickLabel = '';
            app.OriginalImageAxes.YTickLabel = '';
            app.TUCIL4IF4073Label = uilabel(app.UIFigure);
            app.TUCIL4IF4073Label.Text = 'TUCIL 4 IF4073';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FileinputLabel = uilabel(app.UIFigure);
            app.FileinputLabel.Text = 'File input';
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = '';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.HasilPengenalanBuaholehPretrainedCNNPanel = uipanel(app.UIFigure);
            app.HasilPengenalanBuaholehPretrainedCNNPanel.Title = 'Hasil Pengenalan Buah oleh Pretrained CNN';
            app.NamabuahCNNLabel = uilabel(app.HasilPengenalanBuaholehPretrainedCNNPanel);
            app.NamabuahCNNLabel.Text = 'Nama buah';
            app.FruitCNNLabel = uilabel(app.HasilPengenalanBuaholehPretrainedCNNPanel);
            app.FruitCNNLabel.Text = '';
            app.ProbCNNLabel = uilabel(app.HasilPengenalanBuaholehPretrainedCNNPanel);
            app.ProbCNNLabel.Text = '';
            app.ProbabilityCNNLabel = uilabel(app.HasilPengenalanBuaholehPretrainedCNNPanel);
            app.ProbabilityCNNLabel.Text = 'Probabilitas (%)';
            app.HasilPengenalanBuahdenganPengolahanCitraPanel = uipanel(app.UIFigure);
            app.HasilPengenalanBuahdenganPengolahanCitraPanel.Title = 'Hasil Pengenalan Buah dengan Pengolahan Citra';
            app.NamabuahCNNLabel_2 = uilabel(app.HasilPengenalanBuahdenganPengolahanCitraPanel);
            app.NamabuahCNNLabel_2.Text = 'Nama buah';
            app.FruitKNNLabel = uilabel(app.HasilPengenalanBuahdenganPengolahanCitraPanel);
            app.FruitKNNLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end